classdef Channel_Renaming_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelRenamingUIFigure  matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        UITable_2                matlab.ui.control.Table
        RawLabels                matlab.ui.control.Label
        PreviewLabel             matlab.ui.control.Label
        DeblankButton            matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        DeleteButton             matlab.ui.control.Button
        EditField_2              matlab.ui.control.EditField
        AddPrefixButton          matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        EditField_3              matlab.ui.control.EditField
        DeletefirstNButton       matlab.ui.control.Button
        OldEdit                  matlab.ui.control.EditField
        NewEdit                  matlab.ui.control.EditField
        OldLabel                 matlab.ui.control.Label
        NewLabel                 matlab.ui.control.Label
        ReplaceButton            matlab.ui.control.Button
    end


    properties (Access = private)
        D; % Description
        Channel_Labels_Raw;
        Channel_Labels_New;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {'A1';'A2';'A3';'...'};
            app.UITable_2.ColumnEditable = true;
            app.EditField_2.Value = 'ChRename_';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                [filename, path] = uigetfile('*.mat','Please select mat file to load');
                filename_full = [path filename];
                app.D = spm_eeg_load(filename_full);
                app.Channel_Labels_Raw = app.D.chanlabels';
                app.UITable.Data = app.Channel_Labels_Raw;
                app.ChannelRenamingUIFigure.Visible = 'Off';
                app.ChannelRenamingUIFigure.Visible = 'On';
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

        end

        % Button pushed function: DeblankButton
        function DeblankButtonPushed(app, event)
            app.Channel_Labels_New = Deblank_Names(app.Channel_Labels_Raw);
            app.UITable_2.Data = app.Channel_Labels_New;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.D = struct(app.D);
            for i = 1:length(app.Channel_Labels_New)
            app.D.channels(i).label = app.Channel_Labels_New{i};
            end
            app.D = meeg(app.D);
            save(app.D)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            Pattern = app.EditField.Value;
            app.Channel_Labels_New = Remove_Name_Pattern(app.Channel_Labels_New,Pattern);
            app.UITable_2.Data = app.Channel_Labels_New;
        end

        % Button pushed function: AddPrefixButton
        function AddPrefixButtonPushed(app, event)
            Prefix = repmat(app.EditField_2.Value,length(app.Channel_Labels_New),1);
            app.Channel_Labels_New = strcat(Prefix,app.Channel_Labels_New);
            app.UITable_2.Data = app.Channel_Labels_New;
        end

        % Button pushed function: DeletefirstNButton
        function DeletefirstNButtonPushed(app, event)
            Label_start = str2double(app.EditField_3.Value);
            app.Channel_Labels_New = cellfun(@(x) x(Label_start+1:end),app.Channel_Labels_New,'UniformOutput',false);
            app.UITable_2.Data = app.Channel_Labels_New;
        end

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            Old = app.OldEdit.Value;
            New = app.NewEdit.Value;
            app.Channel_Labels_New = Replace_Name_Pattern(app.Channel_Labels_New,Old,New);
            app.UITable_2.Data = app.Channel_Labels_New;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelRenamingUIFigure = uifigure;
            app.ChannelRenamingUIFigure.Name = 'Channel Renaming';
            app.UITable = uitable(app.ChannelRenamingUIFigure);
            app.UITable.ColumnName = {'Channel Names'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable_2 = uitable(app.ChannelRenamingUIFigure);
            app.UITable_2.ColumnName = {'Channel Names'};
            app.UITable_2.RowName = {};
            app.RawLabels = uilabel(app.ChannelRenamingUIFigure);
            app.RawLabels.Text = 'Raw Labels';
            app.PreviewLabel = uilabel(app.ChannelRenamingUIFigure);
            app.PreviewLabel.Text = 'Preview';
            app.DeblankButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.DeblankButton.ButtonPushedFcn = createCallbackFcn(app, @DeblankButtonPushed, true);
            app.DeblankButton.Text = 'Deblank';
            app.EditField = uieditfield(app.ChannelRenamingUIFigure, 'text');
            app.EditField.Value = '-Ref';
            app.DeleteButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.EditField_2 = uieditfield(app.ChannelRenamingUIFigure, 'text');
            app.AddPrefixButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.AddPrefixButton.ButtonPushedFcn = createCallbackFcn(app, @AddPrefixButtonPushed, true);
            app.AddPrefixButton.Text = 'Add Prefix';
            app.SaveButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.EditField_3 = uieditfield(app.ChannelRenamingUIFigure, 'text');
            app.EditField_3.Value = '3';
            app.DeletefirstNButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.DeletefirstNButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefirstNButtonPushed, true);
            app.DeletefirstNButton.Text = 'Delete first N';
            app.OldEdit = uieditfield(app.ChannelRenamingUIFigure, 'text');
            app.NewEdit = uieditfield(app.ChannelRenamingUIFigure, 'text');
            app.OldLabel = uilabel(app.ChannelRenamingUIFigure);
            app.OldLabel.Text = 'Old';
            app.NewLabel = uilabel(app.ChannelRenamingUIFigure);
            app.NewLabel.Text = 'New';
            app.ReplaceButton = uibutton(app.ChannelRenamingUIFigure, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Channel_Renaming_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelRenamingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelRenamingUIFigure)
        end
    end
end